classdef applab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        LogTextArea  matlab.ui.control.TextArea
        TabGroup     matlab.ui.container.TabGroup
        PIVTab       matlab.ui.container.Tab
    end

    
    properties (Access = private)
        module = struct() % to store imported modules;
        queue_pool = struct('log', parallel.pool.DataQueue, 'disp', parallel.pool.DataQueue) % to store queue pool
    end
    
    methods (Access = private)
        % define logger function
        function log(app, message)
            % INPUT:
            %       message - displayed string
            app.LogTextArea.Value = [app.LogTextArea.Value; strcat(string(datetime), " ", message)];
            scroll(app.LogTextArea, 'bottom');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            % initialize functions to workers
            afterEach(app.queue_pool.log, @app.log)
            afterEach(app.queue_pool.disp, @disp)
            
            % create piv interface section
            app.module.piv = piv(app.PIVTab, app.queue_pool);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % forced deleting TCP server instance
            try
                delete(app.module.piv.tcp_server)
            catch
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PIVTab = uitab(app.TabGroup);
            app.PIVTab.Title = 'PIV';
            app.LogTextArea = uitextarea(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = applab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end